classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Label           matlab.ui.control.Label
        MethodName      matlab.ui.control.DropDown
        RunBtn          matlab.ui.control.Button
        Panel           matlab.ui.container.Panel
        y0SpinnerLabel  matlab.ui.control.Label
        y0Spinner       matlab.ui.control.Spinner
        x0SpinnerLabel  matlab.ui.control.Label
        x0Spinner       matlab.ui.control.Spinner
        v0SpinnerLabel  matlab.ui.control.Label
        v0Spinner       matlab.ui.control.Spinner
        x1SpinnerLabel  matlab.ui.control.Label
        x1Spinner       matlab.ui.control.Spinner
        y1SpinnerLabel  matlab.ui.control.Label
        y1Spinner       matlab.ui.control.Spinner
        UIAxes          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunBtn
        function RunBtnButtonPushed(app, event)
            methodName = app.MethodName.Value;
            app.UIAxes.Title.String = sprintf('График (%s метод)', methodName);
            x1 = app.x1Spinner.Value;
            y1 = app.y1Spinner.Value;
            
            x = zeros(10, 1);
            y = zeros(10, 1);
            x(1) = app.x0Spinner.Value;
            y(1) = app.y0Spinner.Value;
            v0 = app.v0Spinner.Value;
            for i = 2 : 10
                x(i) = x(1) + v0 * i;
                y(i) = y(1) + v0 * i;
            end
            plot(app.UIAxes, x, y);
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, x(1), y(1), 'bx');
            plot(app.UIAxes, x1, y1, 'rx');
            hold(app.UIAxes, 'off');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Ортодромия';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Метод';
            app.MethodName = uidropdown(app.UIFigure);
            app.MethodName.Items = {'Маршрутный', 'Курсовой'};
            app.MethodName.Value = 'Маршрутный';
            app.RunBtn = uibutton(app.UIFigure, 'push');
            app.RunBtn.ButtonPushedFcn = createCallbackFcn(app, @RunBtnButtonPushed, true);
            app.RunBtn.Text = 'Запуск';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Параметры ОУ';
            app.y0SpinnerLabel = uilabel(app.Panel);
            app.y0SpinnerLabel.Text = 'y0';
            app.y0Spinner = uispinner(app.Panel);
            app.x0SpinnerLabel = uilabel(app.Panel);
            app.x0SpinnerLabel.Text = 'x0';
            app.x0Spinner = uispinner(app.Panel);
            app.v0SpinnerLabel = uilabel(app.Panel);
            app.v0SpinnerLabel.Text = 'v0';
            app.v0Spinner = uispinner(app.Panel);
            app.v0Spinner.Value = 1;
            app.x1SpinnerLabel = uilabel(app.Panel);
            app.x1SpinnerLabel.Text = 'x1';
            app.x1Spinner = uispinner(app.Panel);
            app.x1Spinner.Value = 5;
            app.y1SpinnerLabel = uilabel(app.Panel);
            app.y1SpinnerLabel.Text = 'y1';
            app.y1Spinner = uispinner(app.Panel);
            app.y1Spinner.Value = 5;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'График')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end